classdef sensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ParkingSensorLabel      matlab.ui.control.Label
        Lamp_1                  matlab.ui.control.Lamp
        Lamp_8                  matlab.ui.control.Lamp
        Lamp_7                  matlab.ui.control.Lamp
        Lamp_6                  matlab.ui.control.Lamp
        Lamp_5                  matlab.ui.control.Lamp
        Lamp_4                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        TimeEditField           matlab.ui.control.NumericEditField
        TimeEditFieldLabel      matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
        GaugeLabel              matlab.ui.control.Label
        Lamp_2                  matlab.ui.control.Lamp
        Lamp_9                  matlab.ui.control.Lamp
    end

 
    methods (Access = public)
 
        function results = Update(app)
            
            close all; fclose all; delete(instrfind); clc; % Start Fresh
            PLOTTING_LENGTH = 10; % Change this to adjust max seconds to show on plot
            Fs = 50; % Arduino Sampling Frequency
            
            %% Connect to Arduino through Serial
            ARDUINO_SERIAL_PORT = '/dev/cu.usbmodem145201'; % Change this (Win: 'COM?', MAC: '/dev/cu.usbmodem?') 
            arduinoSerial = serial(ARDUINO_SERIAL_PORT,'baudrate',9600,'InputBufferSize',5120); % Create Serial Object a to connect to arduino (choose com port correctly)
            fopen(arduinoSerial); % Open Serial communcation
            pause(1) % Wait data on Serial
            fgetl(arduinoSerial); % Take out broken first line in serial
            %% Initialize Plotting axis
            fig1 = figure();
            set(fig1,'Units','normalized')
            set(fig1,'Position',[0.1,0.1,0.8,0.8])
            ii = 0; % Initialize counter
            
            %% Main Loop
            while ishghandle(fig1)
                while arduinoSerial.BytesAvailable %Read until serial is empty
                    htic1 = tic;
                    while toc(htic1)<0.05 %Read for 0.05 seconds before checking if serial is empty
                        %% Read a line in serial and put data into arrays
                        ii = ii + 1;
                        [timestr,distancestr] = strtok(fgetl(arduinoSerial),' ');
                        time(ii) = str2num(timestr) / 1000;
                        sens(ii) = str2num(distancestr);
                         if (sens(ii) == 0) or (sens(ii) >= 80)
                             lightoff(app);
                             app.Lamp_9.Visible = 'on';
                         end
                         if (sens(ii) >= 60) && (sens(ii) < 80)
                             lightoff(app);
                             app.Lamp_8.Visible = 'on';
                         end
                         if (sens(ii) >= 45) && (sens(ii) < 60)
                             lightoff(app);
                             app.Lamp_7.Visible = 'on';
                         end
                         if (sens(ii) >= 25) && (sens(ii) < 45)
                             lightoff(app);
                             app.Lamp_6.Visible = 'on';
                         end
                         if (sens(ii) >= 15) && (sens(ii) < 25)
                             lightoff(app);
                             app.Lamp_5.Visible = 'on';
                         end
                         if (sens(ii) >= 10) && (sens(ii) < 15)
                             lightoff(app);
                             app.Lamp_4.Visible = 'on';
                         end
                         if (sens(ii) >= 7) && (sens(ii) < 10)
                             lightoff(app);
                             app.Lamp_3.Visible = 'on';
                         end
                         if (sens(ii) >= 3) && (sens(ii) < 7)
                             lightoff(app);
                             app.Lamp_2.Visible = 'on';
                         end
                         if (sens(ii) > 0) && (sens(ii) < 3)
                             lightoff(app);
                             app.Lamp_1.Visible = 'on';
                         end
                        app.TimeEditField.Value = time(ii);
                        app.Gauge.Value = sens(ii);
                        app.DistanceEditField.Value = sens(ii);
                    end
                end
                %% Update plot
                cla() % Clear previous plot
%                 DistanceEditFieldValueChanged(app)
                plot(time,sens,'--','linewidth',2), xlabel('Time [s]'), ylabel('Distance from Obstacle [cm]'), title('Car Parking Sensor Values')
                xlim([time(max(1,ii-Fs*PLOTTING_LENGTH)),time(ii)+0.001]) % Adjust x-axis range to show max 10 seconds of data (can be changed on top)
                pause(0.05) % Give MATLAB time to render plot
            end
            delete(arduinoSerial) % Close serial connection

        end
        
    end

    
    methods (Access = private)
        
        function results = lightoff(app)
            app.Lamp_1.Visible = 'off';
            app.Lamp_2.Visible = 'off';
            app.Lamp_3.Visible = 'off';
            app.Lamp_4.Visible = 'off';
            app.Lamp_5.Visible = 'off';
            app.Lamp_6.Visible = 'off';
            app.Lamp_7.Visible = 'off';
            app.Lamp_8.Visible = 'off';
            app.Lamp_9.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Update(app)        
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 80];
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_1 = uilamp(app.UIFigure);
            app.ParkingSensorLabel = uilabel(app.UIFigure);
            app.ParkingSensorLabel.Text = 'Parking Sensor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end